classdef DaoHam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ohmTab                          matlab.ui.container.Tab
        SolveButton                     matlab.ui.control.Button
        dhketqua                        matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_6           matlab.ui.control.Label
        dhgiatri                        matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_5           matlab.ui.control.Label
        ChoosethewrongnumberDropDown    matlab.ui.control.DropDown
        ChoosethewrongnumberDropDownLabel  matlab.ui.control.Label
        EnteryLabel                     matlab.ui.control.Label
        EnterfxLabel                    matlab.ui.control.Label
        EnterxLabel                     matlab.ui.control.Label
        dhfx                            matlab.ui.control.EditField
        SelectinputtypeDropDown         matlab.ui.control.DropDown
        SelectinputtypeDropDownLabel    matlab.ui.control.Label
        dhh                             matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_3           matlab.ui.control.Label
        dhy                             matlab.ui.control.EditField
        dhx                             matlab.ui.control.EditField
        ChoosederivativemethodDropDown  matlab.ui.control.DropDown
        ChoosederivativemethodDropDownLabel  matlab.ui.control.Label
        gx_5                            matlab.ui.control.Label
    end

    properties (Access = private)
        selectedMethod              string
        selectedErrorOrder          string
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectinputtypeDropDown
        function SelectinputtypeDropDownValueChanged(app, event)
            value = app.SelectinputtypeDropDown.Value;
            switch value 
                case 'Dữ liệu x,y'
                    app.dhx.Visible = 'on';
                    app.dhy.Visible = 'on';
                    app.dhfx.Visible = 'off';
                    app.EnterxLabel.Visible = 'on';
                    app.EnterfxLabel.Visible = 'off';
                    app.EnteryLabel.Visible = 'on';
                case 'Hàm số'
                    app.dhx.Visible = 'off';
                    app.dhy.Visible = 'off';
                    app.dhfx.Visible = 'on';
                    app.EnterxLabel.Visible = 'off';
                    app.EnterfxLabel.Visible = 'on';
                    app.EnteryLabel.Visible = 'off';
            end
        end

        % Value changed function: ChoosederivativemethodDropDown
        function ChoosederivativemethodDropDownValueChanged(app, event)
            value = app.ChoosederivativemethodDropDown.Value; 
            switch value
                case 'Xấp xỉ tiến'
                    app.selectedMethod = 'forward';
                case 'Xấp xỉ lùi'
                    app.selectedMethod = 'backward';
                case 'Xấp xỉ trung tâm'
                    app.selectedMethod = 'central';
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
method = app.selectedMethod;
h = app.dhh.Value;
x = app.dhgiatri.Value;

% Kiểm tra nếu các giá trị nhập vào hợp lệ
if isempty(method) || isempty(app.selectedErrorOrder)
    disp('Chưa chọn phương pháp hoặc sai số.');
    return;
end

result = 0; % Khởi tạo biến result trước khi sử dụng

% Kiểm tra xem có phải là phương pháp 'Hàm số' hay không
if strcmp(app.SelectinputtypeDropDown.Value, 'Hàm số')
    try
        fx = str2func(['@(x)' app.dhfx.Value]); % Chuyển chuỗi thành hàm
        disp(fx); % Kiểm tra hàm đã được tạo đúng chưa

        switch method
            case 'forward'
                switch app.selectedErrorOrder
                    case 'O(h)'
                        result = (fx(x + h) - fx(x)) / h;
                    case 'O(h^2)'
                        result = (-fx(x + 2*h) + 4*fx(x + h) - 3*fx(x)) / (2*h);
                end
            case 'backward'
                switch app.selectedErrorOrder
                    case 'O(h)'
                        result = (fx(x) - fx(x - h)) / h;
                    case 'O(h^2)'
                        result = (3*fx(x) - 4*fx(x - h) + fx(x - 2*h)) / (2*h);
                end
            case 'central'
                switch app.selectedErrorOrder
                    case 'O(h)'
                        result = (fx(x + h) - fx(x - h)) / (2*h);
                    case 'O(h^2)'
                        result = (-fx(x + 2*h) + 8*fx(x + h) - 8*fx(x - h) + fx(x - 2*h)) / (12*h);
                end
        end
    catch ME
        disp('Lỗi khi chuyển chuỗi thành hàm:');
        disp(ME.message);
        return;
    end
else
    % Trường hợp Dữ liệu x, y
    try
        % Lấy dữ liệu x, y từ các ô nhập
        x_data = str2num(app.dhx.Value);  % Chuyển chuỗi nhập thành mảng x
        y_data = str2num(app.dhy.Value);  % Chuyển chuỗi nhập thành mảng y

        % Kiểm tra xem x và y có cùng chiều dài không
        if length(x_data) ~= length(y_data)
            disp('Số lượng điểm x và y không khớp.');
            return;
        end

        % Tìm chỉ số của điểm x_val trong mảng x_data
        idx = find(x_data == x);
        
        % Kiểm tra chỉ số có hợp lệ không (tránh ra ngoài biên của mảng)
        if isempty(idx) || idx == 1 || idx == length(x_data)
            disp('Không thể tính đạo hàm tại điểm này.');
            return;
        end

        % Tính đạo hàm tại điểm x_val
        switch method
            case 'forward'
                result = (y_data(idx + 1) - y_data(idx)) / h;
            case 'backward'
                result = (y_data(idx) - y_data(idx - 1)) / h;
            case 'central'
                result = (y_data(idx + 1) - y_data(idx - 1)) / (2 * h);
        end
    catch ME
        disp('Lỗi khi xử lý dữ liệu x, y:');
        disp(ME.message);
        return;
    end
end

% Hiển thị kết quả
disp(['Kết quả: ', num2str(result)]);
app.dhketqua.Value = result;

        end

        % Value changed function: ChoosethewrongnumberDropDown
        function ChoosethewrongnumberDropDownValueChanged(app, event)
           value = app.ChoosethewrongnumberDropDown.Value; 
            switch value 
                case 'O(h)'
                    app.selectedErrorOrder = 'O(h)';
                case 'O(h^2)'
                    app.selectedErrorOrder = 'O(h^2)';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.gx_5 = uilabel(app.ohmTab);
            app.gx_5.Text = 'ĐẠO HÀM';
            app.ChoosederivativemethodDropDownLabel = uilabel(app.ohmTab);
            app.ChoosederivativemethodDropDownLabel.Text = 'Choose derivative method';
            app.ChoosederivativemethodDropDown = uidropdown(app.ohmTab);
            app.ChoosederivativemethodDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ChoosederivativemethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosederivativemethodDropDownValueChanged, true);
            app.ChoosederivativemethodDropDown.Value = 'Xấp xỉ tiến';
            app.dhx = uieditfield(app.ohmTab, 'text');
            app.dhy = uieditfield(app.ohmTab, 'text');
            app.NhpgitrcnnisuyLabel_3 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_3.Text = 'Enter step h value:';
            app.dhh = uieditfield(app.ohmTab, 'numeric');
            app.SelectinputtypeDropDownLabel = uilabel(app.ohmTab);
            app.SelectinputtypeDropDownLabel.Text = 'Select input type';
            app.SelectinputtypeDropDown = uidropdown(app.ohmTab);
            app.SelectinputtypeDropDown.Items = {'...', 'Dữ liệu x,y', 'Hàm số'};
            app.SelectinputtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectinputtypeDropDownValueChanged, true);
            app.SelectinputtypeDropDown.Value = '...';
            app.dhfx = uieditfield(app.ohmTab, 'text');
            app.EnterxLabel = uilabel(app.ohmTab);
            app.EnterxLabel.Text = 'Enter x: ';
            app.EnterfxLabel = uilabel(app.ohmTab);
            app.EnterfxLabel.Text = 'Enter f(x):';
            app.EnteryLabel = uilabel(app.ohmTab);
            app.EnteryLabel.Text = 'Enter y:';
            app.ChoosethewrongnumberDropDownLabel = uilabel(app.ohmTab);
            app.ChoosethewrongnumberDropDownLabel.Text = 'Choose the wrong number';
            app.ChoosethewrongnumberDropDown = uidropdown(app.ohmTab);
            app.ChoosethewrongnumberDropDown.Items = {'O(h)', 'O(h^2)'};
            app.ChoosethewrongnumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosethewrongnumberDropDownValueChanged, true);
            app.ChoosethewrongnumberDropDown.Value = 'O(h)';
            app.NhpgitrcnnisuyLabel_5 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_5.Text = 'Enter x0';
            app.dhgiatri = uieditfield(app.ohmTab, 'numeric');
            app.NhpgitrcnnisuyLabel_6 = uilabel(app.ohmTab);
            app.NhpgitrcnnisuyLabel_6.Text = 'Result;';
            app.dhketqua = uieditfield(app.ohmTab, 'numeric');
            app.SolveButton = uibutton(app.ohmTab, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DaoHam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end