classdef FINAL_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tim_NghiemTab                 matlab.ui.container.Tab
        ChoosesolutionmethodDropDown  matlab.ui.control.DropDown
        ChoosesolutionmethodDropDown_2Label  matlab.ui.control.Label
        Labelgx                       matlab.ui.control.Label
        ptgx                          matlab.ui.control.EditField
        Printf                        matlab.ui.control.TextArea
        PrintfTextArea_2Label         matlab.ui.control.Label
        TMNGHIMGNNGLabel              matlab.ui.control.Label
        saisochophep                  matlab.ui.control.NumericEditField
        EntertheallowederrorLabel     matlab.ui.control.Label
        pt                            matlab.ui.control.EditField
        NhpphngtrnhLabel              matlab.ui.control.Label
        fab                           matlab.ui.control.Label
        TIM_NGHIEM                    matlab.ui.control.Button
        b0                            matlab.ui.control.NumericEditField
        a0                            matlab.ui.control.NumericEditField
        UIAxes_2                      matlab.ui.control.UIAxes
        Noi_SuyTab                    matlab.ui.container.Tab
        HienthiTextArea               matlab.ui.control.TextArea
        ATHCNISUYLabel                matlab.ui.control.Label
        HienthiTextAreaLabel          matlab.ui.control.Label
        NhapgiatricannoisuyEditField  matlab.ui.control.EditField
        NhapgiatricannoisuyEditFieldLabel  matlab.ui.control.Label
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        ChonphuongphapDropDownLabel   matlab.ui.control.Label
        NhapgiatriyEditField          matlab.ui.control.EditField
        NhapgiatriyEditFieldLabel     matlab.ui.control.Label
        NhapgiatrixEditField          matlab.ui.control.EditField
        NhapgiatrixEditFieldLabel     matlab.ui.control.Label
        VehamsoButton                 matlab.ui.control.Button
        KetquanoisuyButton            matlab.ui.control.Button
        KetquadathucButton            matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Hoi_QuyTab                    matlab.ui.container.Tab
        ChnphngphphiquyDropDownLabel  matlab.ui.control.Label
        nskq                          matlab.ui.control.TextArea
        KtquTextAreaLabel             matlab.ui.control.Label
        PtrHQ                         matlab.ui.control.Button
        nsgt                          matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        nsy                           matlab.ui.control.EditField
        NhpphngtrnhLabel_3            matlab.ui.control.Label
        nsx                           matlab.ui.control.EditField
        NhpphngtrnhLabel_2            matlab.ui.control.Label
        HOI_QUY                       matlab.ui.control.Button
        ChnphngphphiquyDropDown       matlab.ui.control.DropDown
        gx                            matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        Dao_HamTab                    matlab.ui.container.Tab
        dhgiatri                      matlab.ui.control.EditField
        EnterstephvalueLabel_2        matlab.ui.control.Label
        dhh                           matlab.ui.control.EditField
        EnterstephvalueLabel          matlab.ui.control.Label
        PrintfTextArea                matlab.ui.control.TextArea
        PrintfTextAreaLabel           matlab.ui.control.Label
        OHMLabel                      matlab.ui.control.Label
        DAO_HAM                       matlab.ui.control.Button
        EnterfxLabel                  matlab.ui.control.Label
        dhfx                          matlab.ui.control.EditField
        EnteryLabel                   matlab.ui.control.Label
        EnterxLabel                   matlab.ui.control.Label
        dhy                           matlab.ui.control.EditField
        dhx                           matlab.ui.control.EditField
        ChoosethewrongnumberDropDown  matlab.ui.control.DropDown
        ChoosethewrongnumberDropDownLabel  matlab.ui.control.Label
        SelectinputtypeDropDown       matlab.ui.control.DropDown
        SelectinputtypeDropDownLabel  matlab.ui.control.Label
        ChoosemethodDropDown          matlab.ui.control.DropDown
        ChoosemethodDropDownLabel     matlab.ui.control.Label
        Tich_PhanTab                  matlab.ui.container.Tab
        TCHPHNLabel                   matlab.ui.control.Label
        Ket_QuaTextArea               matlab.ui.control.TextArea
        PrintfTextArea_2Label_2       matlab.ui.control.Label
        tpN                           matlab.ui.control.EditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        duoi                          matlab.ui.control.EditField
        CndiEditFieldLabel            matlab.ui.control.Label
        tren                          matlab.ui.control.EditField
        CntrnEditFieldLabel           matlab.ui.control.Label
        TICH_PHAN                     matlab.ui.control.Button
        fab_2                         matlab.ui.control.Label
        ChndnguvoDropDown             matlab.ui.control.DropDown
        ChndnguvoDropDownLabel        matlab.ui.control.Label
        ChnphngphpohmDropDown         matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel    matlab.ui.control.Label
        NhpdliuyLabel                 matlab.ui.control.Label
        NhphmLabel                    matlab.ui.control.Label
        NhpdliuxLabel                 matlab.ui.control.Label
        tpfx                          matlab.ui.control.EditField
        tpy                           matlab.ui.control.EditField
        tpx                           matlab.ui.control.EditField
        Gioi_Thieu_NhomTab            matlab.ui.container.Tab
        DanhschthnhvinLabel           matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Nhm6_LpCLC1Label              matlab.ui.control.Label
        ChonthanhvienDropDown         matlab.ui.control.DropDown
        ChonthanhvienDropDownLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KetquadathucButton
        function KetquadathucButtonPushed(app, event)
            xa = str2num(app.NhapgiatrixEditField.Value);
            ya = str2num(app.NhapgiatriyEditField.Value);
            syms X;

            if length(xa) ~= length(ya)
                app.HienthiTextArea.Value = sprintf('Vui long nhap cac gia tri x va y co cung kich thuoc!!');
                return;
            end

            if strcmp(app.ChonphuongphapDropDown.Value, 'Newton')
                n = length(xa);
                d = ya;
                for i = 2 : n
                    for j = n : -1 : i
                        d(j) = (d(j) - d(j-1)) / (xa(j) - xa(j-i+1));
                    end
                end
                
                result = d(1);
                for k = 2 : n
                    result = result * (X - xa(k - 1)) + d(k);
                end
                result = simplify(result);
                app.HienthiTextArea.Value = sprintf('Đa thức nội suy Newton:\n%s', char(result)); 

            elseif strcmp(app.ChonphuongphapDropDown.Value, 'Lagrange')
                n = length(xa);
                sum = 0;
                for i = 1 : n
                    product = ya(i);
                    for j = 1 : n
                        if j ~= i
                            product = product * ((X - xa(j)) / (xa(i) - xa(j)));
                        end
                    end
                    sum = sum + simplify(product);
                end
                result = simplify(sum);
                app.HienthiTextArea.Value = sprintf('Đa thức nội suy Lagrange:\n%s', char(result)); 
            end                        
        end

        % Button pushed function: KetquanoisuyButton
        function KetquanoisuyButtonPushed(app, event)
            xa = str2num(app.NhapgiatrixEditField.Value);
            ya = str2num(app.NhapgiatriyEditField.Value);
            
            if length(xa) ~= length(ya)
                app.HienthiTextArea.Value = sprintf('Vui lòng nhập các giá trị x và y có cùng kích thước!!');
                return;
            end

            if isempty(app.NhapgiatricannoisuyEditField.Value)
                app.HienthiTextArea.Value = sprintf('Vui long nhap gia tri xo để thực hiện nội suy!!');
                return;
            else
                xo = str2double(app.NhapgiatricannoisuyEditField.Value);
            end

            if strcmp(app.ChonphuongphapDropDown.Value, 'Newton')
                n = length(xa);
                d = ya;
                for i = 2 : n
                    for j = n : -1 : i
                        d(j) = (d(j) - d(j-1)) / (xa(j) - xa(j-i+1));
                    end
                end
                
                if abs(xo - xa(1)) <= abs(xo - xa(end))
                    %Newton tiến
                    result = d(1);
                    product = 1;
                    for k = 2 : n
                        product = product * (xo - xa(k - 1));
                        result = result + product * d(k);
                    end
                    app.HienthiTextArea.Value = sprintf('Giá trị nội suy tại x = %.2f theo phương pháp Newton tiến là: %.4f', xo, result);
                else
                    %Newton lùi
                    result = d(n);
                    for k = n-1 : -1 : 1
                        result = result * (xo - xa(k)) + d(k);
                    end
                    app.HienthiTextArea.Value = sprintf('Giá trị nội suy tại x = %.2f theo phương pháp Newton lùi là: %.4f', xo, result);
                end

            %Lagrange
            elseif strcmp(app.ChonphuongphapDropDown.Value, 'Lagrange')
                n = length(xa);
                sum = 0;
                for i = 1 : n
                    product = ya(i);
                    for j = 1 : n
                        if j ~= i
                            product = product * ((xo - xa(j)) / (xa(i) - xa(j)));
                        end
                    end
                    sum = sum + product;
                end
                app.HienthiTextArea.Value = sprintf('Giá trị nội suy tại x = %.2f theo phương pháp Lagrange là: %.4f', xo, sum);
            end
        end

        % Button pushed function: VehamsoButton
        function VehamsoButtonPushed(app, event)
            xa = str2num(app.NhapgiatrixEditField.Value);
            ya = str2num(app.NhapgiatriyEditField.Value);
            syms X;

            if length(xa) ~= length(ya)
                app.HienthiTextArea.Value = sprintf('Vui long nhap cac gia tri x va y co cung kich thuoc!!');
                return;
            end

            if strcmp(app.ChonphuongphapDropDown.Value, 'Newton')
                n = length(xa);
                d = ya;
                for i = 2 : n
                    for j = n : -1 : i
                        d(j) = (d(j) - d(j-1)) / (xa(j) - xa(j-i+1));
                    end
                end
                
                % Xây dựng đa thức Newton
                y = d(n);
                for k = n-1 : -1 : 1
                    y = y * (X - xa(k)) + d(k);
                end
                y = simplify(y);

            elseif strcmp(app.ChonphuongphapDropDown.Value, 'Lagrange')
                n = length(xa);
                sum = 0;
                for i = 1 : n
                    product = ya(i);
                    for j = 1 : n
                        if j ~= i
                            product = product * ((X - xa(j)) / (xa(i) - xa(j)));
                        end
                    end
                    sum = sum + simplify(product);
                end
                y = simplify(sum);
            end            

            x = linspace(min(xa), max(xa), 100);
            y = double(subs(y, X, x));

            plot(app.UIAxes, xa, ya, 'ro', 'MarkerSize', 8, 'LineWidth', 2); % Dữ liệu thực
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, y, 'b-', 'LineWidth', 2); % Đa thức nội suy
            hold(app.UIAxes, 'off');

            legend(app.UIAxes, 'Dữ liệu thực', 'Đa thức nội suy');
            title(app.UIAxes, 'Đồ thị hàm nội suy và dữ liệu thực');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
        end

        % Value changed function: ChonthanhvienDropDown
        function ChonthanhvienDropDownValueChanged(app, event)
            t = app.ChonthanhvienDropDown.Value;
            
            if strcmp(t, 'Phạm Gia Thịnh')
                app.UITable.Data = {'Phạm Gia Thịnh', '22207085', 'Hồi Quy và Tích Phân', '100%', 'Hoàn thành đúng thời hạn yêu cầu, nội dung trình bài chính xác'};
            elseif strcmp(t, 'Vũ Trần Thành Trung')
                app.UITable.Data = {'Vũ Trần Thành Trung', '22207095', 'Tìm Nghiệm và Đạo Hàm', '100%', 'Hoàn thành đúng hạn và chính xác'};
            elseif strcmp(t, 'Lê Văn Tuấn Anh')
                app.UITable.Data = {'Lê Văn Tuấn Anh', '22207108', 'Giới thiệu nhóm, Nội Suy và Word', '100%', 'Good'};
            elseif strcmp(t, 'Tất cả thành viên')
                app.UITable.Data = {
                            'Phạm Gia Thịnh', '22207085', 'Hồi Quy và Tích Phân', '100%', 'Hoàn thành đúng thời hạn yêu cầu, nội dung trình bài chính xác';
                            'Vũ Trần Thành Trung', '22207095', 'Tìm Nghiệm và Đạo Hàm', '100%', 'Hoàn thành đúng hạn và chính xác';
                            'Lê Văn Tuấn Anh', '22207108', 'Giới thiệu nhóm, Nội Suy và Word', '100%', 'Good';
                        };
            else
                app.UITable.Visible = 'off';
            end
        end

        % Value changed function: SelectinputtypeDropDown
        function SelectinputtypeDropDownValueChanged(app, event)
            value = app.SelectinputtypeDropDown.Value;
            switch value 
                case 'Dữ liệu x,y'
                    app.dhx.Visible = 'on';
                    app.dhy.Visible = 'on';
                    app.dhfx.Visible = 'off';
                    app.EnterfxLabel.Visible = 'off';
                    app.EnterxLabel.Visible = 'on';
                    app.EnteryLabel.Visible = 'on';
                case 'Hàm số'
                    app.dhx.Visible = 'off';
                    app.dhy.Visible = 'off';
                    app.dhfx.Visible = 'on';
                    app.EnterfxLabel.Visible = 'on';
                    app.EnterxLabel.Visible = 'off';
                    app.EnteryLabel.Visible = 'off';
            end          
        end

        % Button pushed function: DAO_HAM
        function DAO_HAMPushed(app, event)
            method = app.ChoosemethodDropDown.Value;
            ero = app.ChoosethewrongnumberDropDown.Value;
            
            if isempty(app.dhh.Value)
                app.PrintfTextArea.Value = sprintf('Xin hãy nhập sai số!!');
                return;
            end

            if isempty(app.dhgiatri.Value)
                app.PrintfTextArea.Value = sprintf('Xin hãy nhập giá trị cần dự đoán!!');
                return;
            end
            
            h = str2double(app.dhh.Value);
            x = str2double(app.dhgiatri.Value);

            % Kiểm tra xem có phải là phương pháp 'Hàm số' hay không
            if strcmp(app.SelectinputtypeDropDown.Value, 'Hàm số')
                try
                    fx = str2func(['@(x)' app.dhfx.Value]); % Chuyển chuỗi thành hàm      
                    if isempty(fx)
                        app.Printf.Value = sprintf('Dữ liệu đầu vào không hợp lệ. Vui lòng kiểm tra lại.');
                        return;
                    end  

                    switch method
                        case 'Xấp xỉ tiến'
                            switch ero
                                case 'O(h)'
                                    result = (fx(x + h) - fx(x)) / h;
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ tiến (O(h)) là: %.2f', result);
                                case 'O(h^2)'
                                    result = (-fx(x + 2*h) + 4*fx(x + h) - 3*fx(x)) / (2*h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ tiến O(h^2) là: %.2f', result);
                            end
                        case 'Xấp xỉ lùi'
                            switch ero
                                case 'O(h)'
                                    result = (fx(x) - fx(x - h)) / h;
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ lùi (O(h)) là: %.2f', result);
                                case 'O(h^2)'
                                    result = (3*fx(x) - 4*fx(x - h) + fx(x - 2*h)) / (2*h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ lùi O(h^2) là: %.2f', result);
                            end
                        case 'Xấp xỉ trung tâm'
                            switch ero
                                case 'O(h)'
                                    result = (fx(x + h) - fx(x - h)) / (2*h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ trung tâm (O(h)) là: %.2f', result);
                                case 'O(h^2)'
                                    result = (-fx(x + 2*h) + 8*fx(x + h) - 8*fx(x - h) + fx(x - 2*h)) / (12*h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ trung tâm O(h^2) là: %.2f', result);
                            end
                    end
                catch ME
                    app.PrintfTextArea.Value = sprintf('Lỗi khi chuyển chuỗi thành hàm!');
                    return;
                end
            else
                % Trường hợp Dữ liệu x, y
                try
                    % Lấy dữ liệu x, y từ các ô nhập
                    x_data = str2num(app.dhx.Value);  % Chuyển chuỗi nhập thành mảng x
                    y_data = str2num(app.dhy.Value);  % Chuyển chuỗi nhập thành mảng y

                    if isempty(x_data) || isempty(y_data)
                        app.Printf.Value = sprintf('Dữ liệu đầu vào không hợp lệ. Vui lòng kiểm tra lại.');
                        return;
                    end   
            
                    % Kiểm tra xem x và y có cùng chiều dài không
                    if length(x_data) ~= length(y_data)
                        app.PrintfTextArea.Value = sprintf('Số lượng điểm x và y không khớp!!');
                        return;
                    end
            
                    % Tìm chỉ số của điểm x trong x_data
                    idx = find(x_data == x, 1);  % Lấy chỉ số đầu tiên nếu có nhiều điểm trùng
                    
                    % Kiểm tra chỉ số có hợp lệ không (tránh ra ngoài biên của mảng)
                    if isempty(idx) || idx == 1 || idx == length(x_data)
                        app.PrintfTextArea.Value = sprintf('Không thể tính đạo hàm tại điểm này!!');
                        return;
                    end
            
                    % Xử lý trường hợp sai số O(h) và O(h^2) cho phương pháp Dữ liệu x, y
                    switch method
                        case 'Xấp xỉ tiến'
                            switch ero
                                case 'O(h)' 
                                    result = (y_data(idx + 1) - y_data(idx)) / h;
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ tiến (O(h)) là: %.2f', result);
                                case 'O(h^2)' 
                                    if idx + 2 > length(x_data)
                                        app.PrintfTextArea.Value = sprintf('Không đủ dữ liệu để tính O(h^2)!!');
                                        return;
                                    end                                   
                                    result = (-y_data(idx + 2) + 4*y_data(idx + 1) - 3*y_data(idx)) / (2*h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ tiến O(h^2) là: %.2f', result);
                            end
                        case 'Xấp xỉ lùi'
                            switch ero
                                case 'O(h)' 
                                    result = (y_data(idx) - y_data(idx - 1)) / h;
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ lùi (O(h)) là: %.2f', result);
                                case 'O(h^2)' 
                                    if idx - 2 < 1
                                        app.PrintfTextArea.Value = sprintf('Không đủ dữ liệu để tính O(h^2)!!');
                                        return;
                                    end                                    
                                    result = (3*y_data(idx) - 4*y_data(idx - 1) + y_data(idx - 2)) / (2*h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ lùi O(h^2) là: %.2f', result);
                            end
                        case 'Xấp xỉ trung tâm'
                            switch ero
                                case 'O(h)' 
                                    result = (y_data(idx + 1) - y_data(idx - 1)) / (2 * h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ trung tâm (O(h)) là: %.2f', result);
                                case 'O(h^2)' 
                                    if idx + 2 > length(x_data) || idx - 2 < 1
                                        app.PrintfTextArea.Value = sprintf('Không đủ dữ liệu để tính O(h^2)!!');
                                        return;
                                    end  
                                    result = (-y_data(idx + 2) + 8*y_data(idx + 1) - 8*y_data(idx - 1) + y_data(idx - 2)) / (12 * h);
                                    app.PrintfTextArea.Value = sprintf('Đạo hàm cấp 1 theo PP xấp xỉ trung tâm O(h^2) là: %.2f', result);
                            end
                    end          
                catch ME
                    app.PrintfTextArea.Value = sprintf('Lỗi khi xử lý dữ liệu x, y:');
                    return;
                end
            end
        end

        % Button pushed function: TIM_NGHIEM
        function TIM_NGHIEMPushed(app, event)
            method = app.ChoosesolutionmethodDropDown.Value;
            
            % Chuyển biểu thức chuỗi thành biểu thức symbolic mà không sử dụng syms x toàn cục
            fx_sym = str2sym(app.pt.Value); % Biểu thức symbolic f(x)
            fx = matlabFunction(fx_sym, 'Vars', sym('x')); % Chuyển thành hàm MATLAB
            saiso = app.saisochophep.Value;
            a = app.a0.Value;
            b = app.b0.Value;
            
            if isempty(saiso) || isempty(a) || isempty(b) || isempty(fx)
                app.Printf.Value = sprintf('Dữ liệu đầu vào không hợp lệ. Vui lòng kiểm tra lại.');
                return;
            end            

            switch method
                case 'Chia đôi'
                    if fx(a) * fx(b) > 0
                        app.Printf.Value = sprintf('f(a) và f(b) phải trái dấu để sử dụng phương pháp Chia đôi.');
                        return;
                    end                    
                    [result, n] = chia_doi(fx, a, b, saiso);
                    app.Printf.Value = sprintf('Nghiệm của f(x) theo PP Chia đôi là: x = %.2f, số lần lập: %d', result, n);
            
                case 'Newton'
                    dfx = matlabFunction(diff(fx_sym, sym('x')), 'Vars', sym('x')); % Tính đạo hàm
                    ddfx = matlabFunction(diff(dfx(sym('x')), sym('x')), 'Vars', sym('x')); % Tính đạo hàm bậc 2
                
                    if dfx(a) * dfx(b) < 0 || ddfx(a) * ddfx(b) < 0
                        app.Printf.Value = sprintf('Không thỏa mãn điều kiện đầu vào!!');
                        return;
                    end
                
                    if fx(a) * ddfx(a) > 0
                        x0 = a;
                    else
                        x0 = b;
                    end
                
                    [result, n] = newton(fx, dfx, x0, saiso);
                    app.Printf.Value = sprintf('Nghiệm của f(x) theo PP Newton là: x = %.2f, số lần lập: %d', result, n);
                
                case 'Lặp'
                    gx_sym = str2sym(app.ptgx.Value); % Biểu thức symbolic cho g(x)
                    gx = matlabFunction(gx_sym, 'Vars', sym('x')); % Chuyển thành hàm MATLAB
                    dgx = matlabFunction(diff(gx_sym, sym('x')), 'Vars', sym('x')); % Tính đạo hàm của g(x)
                   
                    if max(abs(dgx(linspace(a, b, 100)))) >= 1
                        app.Printf.Value = sprintf('Không thỏa mãn điều kiện đầu vào!!');
                        return;
                    end
            
                    [result, n] = pplap(gx, a, saiso);
                    app.Printf.Value = sprintf('Nghiệm của f(x) theo PP Lặp là: x = %.2f, số lần lập: %d', result, n);
            end
            
            % Hàm chia đôi
            function [result, n] = chia_doi(fx, a, b, saiso)
                n = 0;
                while abs(b-a) > saiso
                    c = (a + b) / 2;
                    if fx(c) == 0
                        break;
                    elseif fx(a) * fx(c) < 0
                        b = c;
                    else
                        a = c;
                    end
                    n = n + 1;
                end
                result = (a + b)/2;
            end
            
            % Hàm Newton
            function [result, n] = newton(fx, dfx, x0, saiso)
                n = 0;
                while true
                    x1 = x0 - fx(x0) / dfx(x0);
                    n = n + 1;
                    if abs(x1 - x0) < saiso
                        break;
                    end
                    x0 = x1;
                end
                result = x0;
            end
            
            % Hàm lặp
            function [result, n] = pplap(gx, x0, saiso)
                n = 0;
                while true
                    x1 = gx(x0);
                    n = n + 1;
                    if abs(x1 - x0) < saiso
                        break;
                    end
                    x0 = x1;
                end
                result = x1;
            end           

            % Vẽ đồ thị
            fplot(app.UIAxes_2, fx, [a, b], 'DisplayName', 'f(x)');
            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, result, fx(result), 'ro', 'DisplayName', 'Nghiệm gần đúng');
            xlabel(app.UIAxes_2, 'x');
            ylabel(app.UIAxes_2, 'f(x)');
            title(app.UIAxes_2, 'Đồ thị của phương trình f(x)');
            legend(app.UIAxes_2, 'show');
            grid(app.UIAxes_2, 'on');
            hold(app.UIAxes_2, 'off');
        end

        % Callback function
        function ChoosesolutionmethodDropDownValueChanged(app, event)
            switch app.ChoosesolutionmethodDropDown.Value
                case 'Chia đôi'
                    app.b0.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';
                    app.ptgx.Visible = 'off'; 
                    app.Labelgx.Visible = 'off';
                case 'Newton'
                    app.b0.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';                    
                    app.ptgx.Visible = 'off'; 
                    app.Labelgx.Visible = 'off';
                case 'Lặp'
                    app.b0.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';
                    app.ptgx.Visible = 'on';   
                    app.Labelgx.Visible = 'on';
            end
        end

        % Button pushed function: HOI_QUY
        function HOI_QUYPushed(app, event)
            x = str2num(app.nsx.Value);             % Chuyển đổi dữ liệu từ chuỗi sang số
            y = str2num(app.nsy.Value);             % Chuyển đổi dữ liệu từ chuỗi sang số
            
            if isempty(x) || isempty(y) || length(x) ~= length(y)
                app.HienthiTextArea.Value = sprintf('Vui lòng nhập dữ liệu hợp lệ cho x và y.');
                return;
            end            
            
            if isempty(app.nsgt.Value)
                app.HienthiTextArea.Value = sprintf('Vui long nhap gia tri xo để thực hiện hồi quy!!');
                return;
            else
                x_predict = str2double(app.nsgt.Value);
            end

            if strcmp(app.ChnphngphphiquyDropDown.Value, 'Tuyến tính')
                % Hồi quy tuyến tính y = a0 + a1*x
                [a1, a] = Hoi_Quy(x, y);
                ya = a + a1*x_predict;        
                app.nskq.Value = sprintf('Kết quả dự đoán hồi quy tuyến tính tại x0: %.2f là: %.4f', x_predict, ya);
                y_d = a + a1*x;
            elseif strcmp(app.ChnphngphphiquyDropDown.Value, 'Hàm mũ')
                % Hồi quy hàm mũ y = a * x^b
                X = log10(x);
                Y = log10(y);
                [A, B] = Hoi_Quy(X, Y);
                b = A;
                a = 10^B;
                ya = a * x_predict^b;      
                app.nskq.Value = sprintf('Kết quả dự đoán hồi quy hàm mũ tại x0: %.2f là: %.4f', x_predict, ya);
                y_d = a * x.^b;
            elseif strcmp(app.ChnphngphphiquyDropDown.Value, 'Logarit')
                % Hồi quy hàm logarit y = A * log(x) + B
                X = log(x);
                Y = y;
                [A, B] = Hoi_Quy(X, Y);
                ya = A * log(x_predict) + B;
                app.nskq.Value = sprintf('Kết quả dự đoán hồi quy logarit tại x0: %.2f là: %.4f', x_predict, ya);
                y_d = A * log(x) + B;
            end

            plot(app.UIAxes2, x, y, 'bo', 'DisplayName', 'Dữ liệu thực');
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2, x, y_d, 'r-', 'LineWidth', 2, 'DisplayName', 'Hàm hồi quy');
            plot(app.UIAxes2, x_predict, ya, 'bs', 'MarkerSize', 8, 'DisplayName', 'Giá trị dự đoán');
            xlabel(app.UIAxes2,'x');
            ylabel(app.UIAxes2,'y');
            title(app.UIAxes2,'Hồi quy tuyến tính và Dự đoán');
            legend(app.UIAxes2,'show');
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'off');

            function [a1, a0] = Hoi_Quy(x, y)
                n = length(x);
                sumx = 0;
                sumy = 0;
                sumxy = 0;
                sumx2 = 0;
                for i = 1:n
                    sumx = sumx + x(i);
                    sumy = sumy + y(i);
                    sumxy = sumxy + x(i)*y(i);
                    sumx2 = sumx2 + x(i)*x(i);
                end 
                xm = sumx/n;
                ym = sumy/n;
                a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                a0 = ym - a1*xm;
            end
        end

        % Value changed function: ChndnguvoDropDown
        function ChndnguvoDropDownValueChanged(app, event)
            value = app.ChndnguvoDropDown.Value;
            switch value
                case 'Dữ liệu x,y'
                    app.tpx.Visible ='on';
                    app.tpy.Visible ='on';
                    app.tpfx.Visible='off';
                    app.NhpdliuxLabel.Visible='on';
                    app.NhphmLabel.Visible ='off';
                    app.NhpdliuyLabel.Visible='on';
                case 'Hàm số'
                    app.tpx.Visible ='off';
                    app.tpy.Visible ='off';
                    app.tpfx.Visible='on';
                    app.NhpdliuxLabel.Visible='off';
                    app.NhphmLabel.Visible ='on';
                    app.NhpdliuyLabel.Visible='off';
            end
        end

        % Button pushed function: TICH_PHAN
        function TICH_PHANPushed(app, event)
            method = app.ChnphngphpohmDropDown.Value;
            form = app.ChndnguvoDropDown.Value;
            x = str2num(app.tpx.Value);
            y = str2num(app.tpy.Value);

            if length(x) ~= length(y)
                app.Ket_QuaTextArea.Value = sprintf('Kích thước x và y phải giống nhau!!');
                return;
            end

            if isempty(app.duoi.Value) || isempty(app.tren.Value) || isempty(app.tpN.Value)
                app.Ket_QuaTextArea.Value = sprintf('Vui lòng nhập đầy đủ các giá trị để thực hiện tính toán!!');
                return;
            else
                a = str2double(app.duoi.Value);
                b = str2double(app.tren.Value);
                N = str2double(app.tpN.Value);
            end

            if N <= 1 || mod(N, 1) ~= 0
                app.Ket_QuaTextArea.Value = sprintf('N phải là số dương và lớn hơn 1!!');
                return;
            end

            if a >= b
                app.Ket_QuaTextArea.Value = sprintf('Cận b phải lớn hơn cận a!!');
                return;
            end
            h = (b - a)/N;

            if strcmp(method, 'Hình thang')
                if strcmp(form, 'Dữ liệu x,y')
                    restp = 0.5 * h * (y(1) + 2 * sum(y(2:end-1)) + y(end));
                    app.Ket_QuaTextArea.Value = sprintf('Tích phân hình thang là: %.4f', restp);
                elseif strcmp(form, 'Hàm số')
                    f = str2func(['@(x)' app.tpfx.Value]);
                    restp = 0.5 * h * (f(a) + 2 * sum(arrayfun(f, linspace(a + h, b - h, N-1))) + f(b));
                    app.Ket_QuaTextArea.Value = sprintf('Tích phân hình thang là: %.4f', restp);
                end
            elseif strcmp(method, 'Simpson 1/3')
                if mod(N, 2) ~= 0
                    app.Ket_QuaTextArea.Value = sprintf('N phải là số chãn!!');
                    return;
                end
                if strcmp(form, 'Dữ liệu x,y')
                    restp = h / 3 * (y(1) + 4 * sum(y(2:2:end-1)) + 2 * sum(y(3:2:end-2)) + y(end));
                    app.Ket_QuaTextArea.Value = sprintf('Tích phân Simpson 1/3 là: %.4f', restp);
                elseif strcmp(form, 'Hàm số')
                    f = str2func(['@(x)' app.tpfx.Value]);
                    restp = h / 3 * (f(a) + 4 * sum(arrayfun(f, linspace(a + h, b - h, N-1))) ...
                                + 2 * sum(arrayfun(f, linspace(a + 2*h, b - 2*h, N/2-1))) + f(b));
                    app.Ket_QuaTextArea.Value = sprintf('Tích phân Simpson 1/3 là: %.4f', restp);
                end  
            elseif strcmp(method, 'Simpson 3/8')
                if mod(N, 3) ~= 0
                    app.Ket_QuaTextArea.Value = sprintf('N phải là số chia hết cho 3!!');
                    return;
                end
                if strcmp(form, 'Dữ liệu x,y')
                    restp = (3*h/8) * (y(1) + 3*sum(y(2:3:end-2)) + 3*sum(y(3:3:end-1)) ...
                                + 2*sum(y(4:3:end-3)) + y(end));
                    app.Ket_QuaTextArea.Value = sprintf('Tích phân Simpson 3/8 là: %.4f', restp);
                elseif strcmp(form, 'Hàm số')
                    fx = str2func(['@(x)' app.tpfx.Value]);
                    restp = (3*h/8) * (fx(a) + 3*sum(arrayfun(fx, linspace(a + h, b - h, N/3))) ...
                                + 3*sum(arrayfun(fx, linspace(a + 2*h, b - 2*h, N/3))) ...
                                + 2*sum(arrayfun(fx, linspace(a + 3*h, b - 3*h, N/3))) + fx(b));
                    app.Ket_QuaTextArea.Value = sprintf('Tích phân Simpson 3/8 là: %.4f', restp);
                end 
            end
        end

        % Button pushed function: PtrHQ
        function PtrHQButtonPushed(app, event)
            x = str2num(app.nsx.Value);             % Chuyển đổi dữ liệu từ chuỗi sang số
            y = str2num(app.nsy.Value);             % Chuyển đổi dữ liệu từ chuỗi sang số

            if isempty(x) || isempty(y) || length(x) ~= length(y)
                app.HienthiTextArea.Value = sprintf('Vui lòng nhập dữ liệu hợp lệ cho x và y.');
                return;
            end            

            if strcmp(app.ChnphngphphiquyDropDown.Value, 'Tuyến tính')
                % Hồi quy tuyến tính y = a0 + a1*x
                [a1, a] = Hoi_Quy(x, y);
                app.nskq.Value = sprintf('Phương trình hồi quy tuyến tính: Y = %.3f + X * %.3f%s', a, a1);
            elseif strcmp(app.ChnphngphphiquyDropDown.Value, 'Hàm mũ')
                % Hồi quy hàm mũ y = a * x^b
                X = log10(x);
                Y = log10(y);
                [A, B] = Hoi_Quy(X, Y);
                b = A;
                a = 10^B;
                app.nskq.Value = sprintf('Phương trình hồi quy hàm mũ: Y = %.3f * X^%.3f%s', a, b);
            elseif strcmp(app.ChnphngphphiquyDropDown.Value, 'Logarit')
                % Hồi quy hàm logarit y = A * log(x) + B
                X = log(x);
                Y = y;
                [A, B] = Hoi_Quy(X, Y);
                app.nskq.Value = sprintf('Phương trình hồi quy logarit: Y = %.3f * log(X) + %.3f', A, B);
            end

            function [a1, a0] = Hoi_Quy(x, y)
                n = length(x);
                sumx = 0;
                sumy = 0;
                sumxy = 0;
                sumx2 = 0;
                for i = 1:n
                    sumx = sumx + x(i);
                    sumy = sumy + y(i);
                    sumxy = sumxy + x(i)*y(i);
                    sumx2 = sumx2 + x(i)*x(i);
                end 
                xm = sumx/n;
                ym = sumy/n;
                a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
                a0 = ym - a1*xm;
            end
        end

        % Value changed function: ChoosesolutionmethodDropDown
        function ChoosesolutionmethodDropDownValueChanged2(app, event)
            switch app.ChoosesolutionmethodDropDown.Value
                case 'Chia đôi'
                    app.b0.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';
                    app.ptgx.Visible = 'off'; 
                    app.Labelgx.Visible = 'off';
                case 'Newton'
                    app.b0.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';                    
                    app.ptgx.Visible = 'off'; 
                    app.Labelgx.Visible = 'off';
                case 'Lặp'
                    app.b0.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';
                    app.ptgx.Visible = 'on';   
                    app.Labelgx.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tim_NghiemTab = uitab(app.TabGroup);
            app.Tim_NghiemTab.Title = 'Tim_Nghiem';
            app.UIAxes_2 = uiaxes(app.Tim_NghiemTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.a0 = uieditfield(app.Tim_NghiemTab, 'numeric');
            app.b0 = uieditfield(app.Tim_NghiemTab, 'numeric');
            app.TIM_NGHIEM = uibutton(app.Tim_NghiemTab, 'push');
            app.TIM_NGHIEM.ButtonPushedFcn = createCallbackFcn(app, @TIM_NGHIEMPushed, true);
            app.TIM_NGHIEM.Text = 'Solve';
            app.fab = uilabel(app.Tim_NghiemTab);
            app.fab.Text = 'Enter [a b]: ';
            app.NhpphngtrnhLabel = uilabel(app.Tim_NghiemTab);
            app.NhpphngtrnhLabel.Text = 'Enter f(x): ';
            app.pt = uieditfield(app.Tim_NghiemTab, 'text');
            app.EntertheallowederrorLabel = uilabel(app.Tim_NghiemTab);
            app.EntertheallowederrorLabel.Text = 'Enter the allowed error:';
            app.saisochophep = uieditfield(app.Tim_NghiemTab, 'numeric');
            app.TMNGHIMGNNGLabel = uilabel(app.Tim_NghiemTab);
            app.TMNGHIMGNNGLabel.Text = 'TÌM NGHIỆM GẦN ĐÚNG';
            app.PrintfTextArea_2Label = uilabel(app.Tim_NghiemTab);
            app.PrintfTextArea_2Label.Text = 'Printf';
            app.Printf = uitextarea(app.Tim_NghiemTab);
            app.ptgx = uieditfield(app.Tim_NghiemTab, 'text');
            app.Labelgx = uilabel(app.Tim_NghiemTab);
            app.Labelgx.Text = 'Enter g(x):';
            app.ChoosesolutionmethodDropDown_2Label = uilabel(app.Tim_NghiemTab);
            app.ChoosesolutionmethodDropDown_2Label.Text = 'Choose solution method';
            app.ChoosesolutionmethodDropDown = uidropdown(app.Tim_NghiemTab);
            app.ChoosesolutionmethodDropDown.Items = {'...', 'Chia đôi', 'Lặp', 'Newton'};
            app.ChoosesolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosesolutionmethodDropDownValueChanged2, true);
            app.ChoosesolutionmethodDropDown.Value = '...';
            app.Noi_SuyTab = uitab(app.TabGroup);
            app.Noi_SuyTab.Title = 'Noi_Suy';
            app.UIAxes = uiaxes(app.Noi_SuyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KetquadathucButton = uibutton(app.Noi_SuyTab, 'push');
            app.KetquadathucButton.ButtonPushedFcn = createCallbackFcn(app, @KetquadathucButtonPushed, true);
            app.KetquadathucButton.Text = 'Ket qua da thuc';
            app.KetquanoisuyButton = uibutton(app.Noi_SuyTab, 'push');
            app.KetquanoisuyButton.ButtonPushedFcn = createCallbackFcn(app, @KetquanoisuyButtonPushed, true);
            app.KetquanoisuyButton.Text = 'Ket qua noi suy';
            app.VehamsoButton = uibutton(app.Noi_SuyTab, 'push');
            app.VehamsoButton.ButtonPushedFcn = createCallbackFcn(app, @VehamsoButtonPushed, true);
            app.VehamsoButton.Text = 'Ve ham so';
            app.NhapgiatrixEditFieldLabel = uilabel(app.Noi_SuyTab);
            app.NhapgiatrixEditFieldLabel.Text = 'Nhap gia tri x';
            app.NhapgiatrixEditField = uieditfield(app.Noi_SuyTab, 'text');
            app.NhapgiatriyEditFieldLabel = uilabel(app.Noi_SuyTab);
            app.NhapgiatriyEditFieldLabel.Text = 'Nhap gia tri y';
            app.NhapgiatriyEditField = uieditfield(app.Noi_SuyTab, 'text');
            app.ChonphuongphapDropDownLabel = uilabel(app.Noi_SuyTab);
            app.ChonphuongphapDropDownLabel.Text = 'Chon phuong phap';
            app.ChonphuongphapDropDown = uidropdown(app.Noi_SuyTab);
            app.ChonphuongphapDropDown.Items = {'Newton', 'Lagrange'};
            app.ChonphuongphapDropDown.Value = 'Newton';
            app.NhapgiatricannoisuyEditFieldLabel = uilabel(app.Noi_SuyTab);
            app.NhapgiatricannoisuyEditFieldLabel.Text = 'Nhap gia tri can noi suy';
            app.NhapgiatricannoisuyEditField = uieditfield(app.Noi_SuyTab, 'text');
            app.HienthiTextAreaLabel = uilabel(app.Noi_SuyTab);
            app.HienthiTextAreaLabel.Text = 'Hien thi';
            app.ATHCNISUYLabel = uilabel(app.Noi_SuyTab);
            app.ATHCNISUYLabel.Text = 'ĐA THỨC NỘI SUY';
            app.HienthiTextArea = uitextarea(app.Noi_SuyTab);
            app.Hoi_QuyTab = uitab(app.TabGroup);
            app.Hoi_QuyTab.Title = 'Hoi_Quy';
            app.UIAxes2 = uiaxes(app.Hoi_QuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.gx = uilabel(app.Hoi_QuyTab);
            app.gx.Text = 'HỒI QUY DỮ LIỆU';
            app.ChnphngphphiquyDropDown = uidropdown(app.Hoi_QuyTab);
            app.ChnphngphphiquyDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính';
            app.HOI_QUY = uibutton(app.Hoi_QuyTab, 'push');
            app.HOI_QUY.ButtonPushedFcn = createCallbackFcn(app, @HOI_QUYPushed, true);
            app.HOI_QUY.Text = 'GIẢI';
            app.NhpphngtrnhLabel_2 = uilabel(app.Hoi_QuyTab);
            app.NhpphngtrnhLabel_2.Text = 'Nhập dữ liệu x: ';
            app.nsx = uieditfield(app.Hoi_QuyTab, 'text');
            app.NhpphngtrnhLabel_3 = uilabel(app.Hoi_QuyTab);
            app.NhpphngtrnhLabel_3.Text = 'Nhập dữ liệu y: ';
            app.nsy = uieditfield(app.Hoi_QuyTab, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.Hoi_QuyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán: ';
            app.nsgt = uieditfield(app.Hoi_QuyTab, 'text');
            app.PtrHQ = uibutton(app.Hoi_QuyTab, 'push');
            app.PtrHQ.ButtonPushedFcn = createCallbackFcn(app, @PtrHQButtonPushed, true);
            app.PtrHQ.Text = 'Kết quả Phương trình';
            app.KtquTextAreaLabel = uilabel(app.Hoi_QuyTab);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.nskq = uitextarea(app.Hoi_QuyTab);
            app.ChnphngphphiquyDropDownLabel = uilabel(app.Hoi_QuyTab);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.Dao_HamTab = uitab(app.TabGroup);
            app.Dao_HamTab.Title = 'Dao_Ham';
            app.ChoosemethodDropDownLabel = uilabel(app.Dao_HamTab);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.Dao_HamTab);
            app.ChoosemethodDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.ChoosemethodDropDown.Value = 'Xấp xỉ tiến';
            app.SelectinputtypeDropDownLabel = uilabel(app.Dao_HamTab);
            app.SelectinputtypeDropDownLabel.Text = 'Select input type';
            app.SelectinputtypeDropDown = uidropdown(app.Dao_HamTab);
            app.SelectinputtypeDropDown.Items = {'...', 'Dữ liệu x,y', 'Hàm số'};
            app.SelectinputtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectinputtypeDropDownValueChanged, true);
            app.SelectinputtypeDropDown.Value = '...';
            app.ChoosethewrongnumberDropDownLabel = uilabel(app.Dao_HamTab);
            app.ChoosethewrongnumberDropDownLabel.Text = 'Choose the wrong number';
            app.ChoosethewrongnumberDropDown = uidropdown(app.Dao_HamTab);
            app.ChoosethewrongnumberDropDown.Items = {'O(h)', 'O(h^2)'};
            app.ChoosethewrongnumberDropDown.Value = 'O(h)';
            app.dhx = uieditfield(app.Dao_HamTab, 'text');
            app.dhy = uieditfield(app.Dao_HamTab, 'text');
            app.EnterxLabel = uilabel(app.Dao_HamTab);
            app.EnterxLabel.Text = 'Enter x: ';
            app.EnteryLabel = uilabel(app.Dao_HamTab);
            app.EnteryLabel.Text = 'Enter y:';
            app.dhfx = uieditfield(app.Dao_HamTab, 'text');
            app.EnterfxLabel = uilabel(app.Dao_HamTab);
            app.EnterfxLabel.Text = 'Enter f(x):';
            app.DAO_HAM = uibutton(app.Dao_HamTab, 'push');
            app.DAO_HAM.ButtonPushedFcn = createCallbackFcn(app, @DAO_HAMPushed, true);
            app.DAO_HAM.Text = 'Solve';
            app.OHMLabel = uilabel(app.Dao_HamTab);
            app.OHMLabel.Text = 'ĐẠO HÀM';
            app.PrintfTextAreaLabel = uilabel(app.Dao_HamTab);
            app.PrintfTextAreaLabel.Text = 'Printf';
            app.PrintfTextArea = uitextarea(app.Dao_HamTab);
            app.EnterstephvalueLabel = uilabel(app.Dao_HamTab);
            app.EnterstephvalueLabel.Text = 'Enter step h value';
            app.dhh = uieditfield(app.Dao_HamTab, 'text');
            app.EnterstephvalueLabel_2 = uilabel(app.Dao_HamTab);
            app.EnterstephvalueLabel_2.Text = 'Enter x0';
            app.dhgiatri = uieditfield(app.Dao_HamTab, 'text');
            app.Tich_PhanTab = uitab(app.TabGroup);
            app.Tich_PhanTab.Title = 'Tich_Phan';
            app.tpx = uieditfield(app.Tich_PhanTab, 'text');
            app.tpy = uieditfield(app.Tich_PhanTab, 'text');
            app.tpfx = uieditfield(app.Tich_PhanTab, 'text');
            app.NhpdliuxLabel = uilabel(app.Tich_PhanTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x: ';
            app.NhphmLabel = uilabel(app.Tich_PhanTab);
            app.NhphmLabel.Text = 'Nhập hàm: ';
            app.NhpdliuyLabel = uilabel(app.Tich_PhanTab);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y: ';
            app.ChnphngphpohmDropDownLabel = uilabel(app.Tich_PhanTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.ChnphngphpohmDropDown = uidropdown(app.Tich_PhanTab);
            app.ChnphngphpohmDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChnphngphpohmDropDown.Value = 'Hình thang';
            app.ChndnguvoDropDownLabel = uilabel(app.Tich_PhanTab);
            app.ChndnguvoDropDownLabel.Text = 'Chọn dạng đầu vào';
            app.ChndnguvoDropDown = uidropdown(app.Tich_PhanTab);
            app.ChndnguvoDropDown.Items = {'...', 'Dữ liệu x,y', 'Hàm số'};
            app.ChndnguvoDropDown.ValueChangedFcn = createCallbackFcn(app, @ChndnguvoDropDownValueChanged, true);
            app.ChndnguvoDropDown.Value = '...';
            app.fab_2 = uilabel(app.Tich_PhanTab);
            app.fab_2.Text = 'Nhập cận tính tích phân:  ';
            app.TICH_PHAN = uibutton(app.Tich_PhanTab, 'push');
            app.TICH_PHAN.ButtonPushedFcn = createCallbackFcn(app, @TICH_PHANPushed, true);
            app.TICH_PHAN.Text = 'Giải';
            app.CntrnEditFieldLabel = uilabel(app.Tich_PhanTab);
            app.CntrnEditFieldLabel.Text = 'Cận trên:';
            app.tren = uieditfield(app.Tich_PhanTab, 'text');
            app.CndiEditFieldLabel = uilabel(app.Tich_PhanTab);
            app.CndiEditFieldLabel.Text = 'Cận dưới';
            app.duoi = uieditfield(app.Tich_PhanTab, 'text');
            app.NhpNEditFieldLabel = uilabel(app.Tich_PhanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N:';
            app.tpN = uieditfield(app.Tich_PhanTab, 'text');
            app.PrintfTextArea_2Label_2 = uilabel(app.Tich_PhanTab);
            app.PrintfTextArea_2Label_2.Text = 'Ket_Qua';
            app.Ket_QuaTextArea = uitextarea(app.Tich_PhanTab);
            app.TCHPHNLabel = uilabel(app.Tich_PhanTab);
            app.TCHPHNLabel.Text = 'TÍCH PHÂN';
            app.Gioi_Thieu_NhomTab = uitab(app.TabGroup);
            app.Gioi_Thieu_NhomTab.Title = 'Gioi_Thieu_Nhom';
            app.ChonthanhvienDropDownLabel = uilabel(app.Gioi_Thieu_NhomTab);
            app.ChonthanhvienDropDownLabel.Text = 'Chon thanh vien';
            app.ChonthanhvienDropDown = uidropdown(app.Gioi_Thieu_NhomTab);
            app.ChonthanhvienDropDown.Items = {'...', 'Phạm Gia Thịnh', 'Vũ Trần Thành Trung', 'Lê Văn Tuấn Anh', 'Tất cả thành viên'};
            app.ChonthanhvienDropDown.ValueChangedFcn = createCallbackFcn(app, @ChonthanhvienDropDownValueChanged, true);
            app.ChonthanhvienDropDown.Value = '...';
            app.Nhm6_LpCLC1Label = uilabel(app.Gioi_Thieu_NhomTab);
            app.Nhm6_LpCLC1Label.Text = 'Nhóm 6 _ Lớp CLC1';
            app.UITable = uitable(app.Gioi_Thieu_NhomTab);
            app.UITable.ColumnName = {'Họ và tên'; 'MSSV'; 'Nhiệm Vụ'; 'Mức độ hoàn thành'; 'Đánh giá'};
            app.UITable.RowName = {};
            app.DanhschthnhvinLabel = uilabel(app.Gioi_Thieu_NhomTab);
            app.DanhschthnhvinLabel.Text = 'Danh sách thành viên';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINAL_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end