classdef HoiQuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HiquyTab                      matlab.ui.container.Tab
        nskq_2                        matlab.ui.control.NumericEditField
        KtqunisuyLabel_2              matlab.ui.control.Label
        nskqpt_2                      matlab.ui.control.EditField
        NhpphngtrnhLabel_7            matlab.ui.control.Label
        GiiButton_3                   matlab.ui.control.Button
        nsgt_2                        matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_2         matlab.ui.control.Label
        nsy_2                         matlab.ui.control.EditField
        NhpphngtrnhLabel_6            matlab.ui.control.Label
        nsx_2                         matlab.ui.control.EditField
        NhpphngtrnhLabel_5            matlab.ui.control.Label
        ChnphngphphiquyDropDown       matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel  matlab.ui.control.Label
        gx_4                          matlab.ui.control.Label
        UIAxes2_2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ChnphngphpgiiDropDownValueChanged(app, event)

        end

        % Callback function
        function GiiButtonPushed(app, event)
       
        end

        % Callback function
        function GiiButton_2Pushed(app, event)
           
        end

        % Button pushed function: GiiButton_3
        function GiiButton_3Pushed(app, event)
           selehq =0;
            switch app.ChnphngphphiquyDropDown.Value
                case 'Tuyến tính'
                    selehq =1;
                case 'Hàm mũ'
                    selehq =2;
                case 'Logarit'
                    selehq =3;
            end
x_str = app.nsx_2.Value;
x = str2num(x_str);           % Chuyển đổi dữ liệu từ chuỗi sang số
y_str = app.nsy_2.Value;
y = str2num(y_str);           % Chuyển đổi dữ liệu từ chuỗi sang số
x_predict = app.nsgt_2.Value; % Giá trị x cần dự đoán

if selehq == 1
    % Hồi quy tuyến tính y = a0 + a1*x
    X = [ones(size(x(:))), x(:)];
    b = regress(y(:), X);
    a0 = b(1);
    a1 = b(2);
    ya = a0 + a1 * x;          % Giá trị dự đoán

    % In phương trình hồi quy bên command window
    regression_equation = sprintf('p(x) = %.4f * x + %.4f', a1, a0);
    fprintf('Phương trình hồi quy: %s\n', regression_equation);

elseif selehq == 2
    % Hồi quy hàm mũ y = A * x^B
    X = log10(x(:));
    Y = log10(y(:));
    B = regress(Y, [ones(size(X)), X]); % Y là log10(y)
    A0 = B(1);
    A1 = B(2);
    A = 10^A0;
    B = A1;
    ya = A * x.^B;             % Giá trị dự đoán

    % In phương trình hồi quy command window
    regression_equation = sprintf('p(x) = %.4f * x^{%.4f}', A, B);
    fprintf('Phương trình hồi quy: %s\n', regression_equation);

elseif selehq == 3
    % Hồi quy hàm logarit y = A * log(x) + B
    X = log(x(:));
    Y = y(:);
    B = regress(Y, [ones(size(X)), X]);
    A0 = B(1);
    A1 = B(2);
    A = A0;
    B = A1;
    ya = A + B * log(x);       % Giá trị dự đoán

    % In phương trình hồi quy command window
    regression_equation = sprintf('p(x) = %.4f * log(x) + %.4f', B, A);
    fprintf('Phương trình hồi quy: %s\n', regression_equation);
end

            app.nskqpt_2.Value = num2str(ya); % Display predicted values
            app.nskq_2.Value = ya(x_predict); % Display single prediction for x_predict

            % Display the regression equation
            app.nskqpt_2.Value = regression_equation;

            % Plotting
            plot(app.UIAxes2_2, x, y, 'o', 'DisplayName', 'Dữ liệu thực');
            hold(app.UIAxes2_2, 'on');
            plot(app.UIAxes2_2, x, ya, 'r-', 'DisplayName', 'Hàm hồi quy');
            plot(app.UIAxes2_2, x_predict, ya(x_predict), 'bs', 'DisplayName', 'Dự đoán');
            xlabel(app.UIAxes2_2, 'x');
            ylabel(app.UIAxes2_2, 'y');
            title(app.UIAxes2_2, 'Hồi quy tuyến tính và Dự đoán');
            legend(app.UIAxes2_2, 'show');
            grid(app.UIAxes2_2, 'on');
            hold(app.UIAxes2_2, 'off');


        end

        % Value changed function: ChnphngphphiquyDropDown
        function ChnphngphphiquyDropDownValueChanged(app, event)
            value = app.ChnphngphphiquyDropDown.Value;
            
        end

        % Callback function
        function ChndnguvoDropDownValueChanged(app, event)
         
        end

        % Callback function
        function GiiButton_4Pushed(app, event)
         
        end

        % Callback function
        function GiiButton_5Pushed(app, event)
           
        end

        % Callback function
        function ChndnguvoDropDown_2ValueChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes2_2 = uiaxes(app.HiquyTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.gx_4 = uilabel(app.HiquyTab);
            app.gx_4.Text = 'HỒI QUY DỮ LIỆU';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChnphngphphiquyDropDown = uidropdown(app.HiquyTab);
            app.ChnphngphphiquyDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChnphngphphiquyDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngphphiquyDropDownValueChanged, true);
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính';
            app.NhpphngtrnhLabel_5 = uilabel(app.HiquyTab);
            app.NhpphngtrnhLabel_5.Text = 'Nhập dữ liệu x: ';
            app.nsx_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpphngtrnhLabel_6 = uilabel(app.HiquyTab);
            app.NhpphngtrnhLabel_6.Text = 'Nhập dữ liệu y: ';
            app.nsy_2 = uieditfield(app.HiquyTab, 'text');
            app.NhpgitrcnnisuyLabel_2 = uilabel(app.HiquyTab);
            app.NhpgitrcnnisuyLabel_2.Text = 'Nhập giá trị cần dự đoán: ';
            app.nsgt_2 = uieditfield(app.HiquyTab, 'numeric');
            app.GiiButton_3 = uibutton(app.HiquyTab, 'push');
            app.GiiButton_3.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_3Pushed, true);
            app.GiiButton_3.Text = 'Giải';
            app.NhpphngtrnhLabel_7 = uilabel(app.HiquyTab);
            app.NhpphngtrnhLabel_7.Text = 'Kết quả phương trình hồi quy: ';
            app.nskqpt_2 = uieditfield(app.HiquyTab, 'text');
            app.KtqunisuyLabel_2 = uilabel(app.HiquyTab);
            app.KtqunisuyLabel_2.Text = 'Kết quả dự đoán ';
            app.nskq_2 = uieditfield(app.HiquyTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoiQuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end