classdef Noi_Suy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HienthiTextArea               matlab.ui.control.TextArea
        HienthiTextAreaLabel          matlab.ui.control.Label
        VehamsoButton                 matlab.ui.control.Button
        KetquanoisuyButton            matlab.ui.control.Button
        NhapgaitricannoisuyEditField  matlab.ui.control.EditField
        NhapgaitricannoisuyEditFieldLabel  matlab.ui.control.Label
        KetquadathucButton            matlab.ui.control.Button
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        ChonphuongphapDropDownLabel   matlab.ui.control.Label
        NhapgiatriyEditField          matlab.ui.control.EditField
        NhapgiatriyEditFieldLabel     matlab.ui.control.Label
        NhapgiatrixEditField          matlab.ui.control.EditField
        NhapgiatrixEditFieldLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KetquanoisuyButton
        function KetquanoisuyButtonPushed(app, event)
            xa = str2num(app.NhapgiatrixEditField.Value);
            ya = str2num(app.NhapgiatriyEditField.Value);
            
            if length(xa) ~= length(ya)
                app.HienthiTextArea.Value = sprintf('Vui lòng nhập các giá trị x và y có cùng kích thước!!');
                return;
            end

            if isempty(app.NhapgaitricannoisuyEditField.Value)
                app.HienthiTextArea.Value = sprintf('Vui long nhap gia tri xo để thực hiện nội suy!!');
                return;
            else
                xo = str2double(app.NhapgaitricannoisuyEditField.Value);
            end

            if strcmp(app.ChonphuongphapDropDown.Value, 'Newton')
                n = length(xa);
                d = ya;
                for i = 2 : n
                    for j = n : -1 : i
                        d(j) = (d(j) - d(j-1)) / (xa(j) - xa(j-i+1));
                    end
                end
                
                if abs(xo - xa(1)) <= abs(xo - xa(end))
                    %Newton tiến
                    result = d(1);
                    product = 1;
                    for k = 2 : n
                        product = product * (xo - xa(k - 1));
                        result = result + product * d(k);
                    end
                    app.HienthiTextArea.Value = sprintf('Giá trị nội suy tại x = %.2f theo phương pháp Newton tiến là: %.4f', xo, result);
                else
                    %Newton lùi
                    result = d(n);
                    for k = n-1 : -1 : 1
                        result = result * (xo - xa(k)) + d(k);
                    end
                    app.HienthiTextArea.Value = sprintf('Giá trị nội suy tại x = %.2f theo phương pháp Newton lùi là: %.4f', xo, result);
                end

            %Lagrange
            elseif strcmp(app.ChonphuongphapDropDown.Value, 'Lagrange')
                n = length(xa);
                sum = 0;
                for i = 1 : n
                    product = ya(i);
                    for j = 1 : n
                        if j ~= i
                            product = product * ((xo - xa(j)) / (xa(i) - xa(j)));
                        end
                    end
                    sum = sum + product;
                end
                app.HienthiTextArea.Value = sprintf('Giá trị nội suy tại x = %.2f theo phương pháp Lagrange là: %.4f', xo, sum);
            end
        end

        % Button pushed function: KetquadathucButton
        function KetquadathucButtonPushed(app, event)
            xa = str2num(app.NhapgiatrixEditField.Value);
            ya = str2num(app.NhapgiatriyEditField.Value);
            syms X;

            if length(xa) ~= length(ya)
                app.HienthiTextArea.Value = sprintf('Vui long nhap cac gia tri x va y co cung kich thuoc!!');
                return;
            end

            if strcmp(app.ChonphuongphapDropDown.Value, 'Newton')
                n = length(xa);
                d = ya;
                for i = 2 : n
                    for j = n : -1 : i
                        d(j) = (d(j) - d(j-1)) / (xa(j) - xa(j-i+1));
                    end
                end
                
                % Xây dựng đa thức nội suy Newton
                result = d(1);
                for k = 2 : n
                    result = result * (X - xa(k - 1)) + d(k);
                end
                result = simplify(result);
                app.HienthiTextArea.Value = sprintf('Đa thức nội suy Newton:\n%s', char(result)); disp(result);

            elseif strcmp(app.ChonphuongphapDropDown.Value, 'Lagrange')
                n = length(xa);
                sum = 0;
                for i = 1 : n
                    product = ya(i);
                    for j = 1 : n
                        if j ~= i
                            product = product * ((X - xa(j)) / (xa(i) - xa(j)));
                        end
                    end
                    sum = sum + simplify(product);
                end
                result = simplify(sum);
                app.HienthiTextArea.Value = sprintf('Đa thức nội suy Lagrange:\n%s', char(result)); disp(result);
            end                        
        end

        % Button pushed function: VehamsoButton
        function VehamsoButtonPushed(app, event)
            xa = str2num(app.NhapgiatrixEditField.Value);
            ya = str2num(app.NhapgiatriyEditField.Value);
            syms X;

            if length(xa) ~= length(ya)
                app.HienthiTextArea.Value = sprintf('Vui long nhap cac gia tri x va y co cung kich thuoc!!');
                return;
            end

            if strcmp(app.ChonphuongphapDropDown.Value, 'Newton')
                n = length(xa);
                d = ya;
                for i = 2 : n
                    for j = n : -1 : i
                        d(j) = (d(j) - d(j-1)) / (xa(j) - xa(j-i+1));
                    end
                end
                
                % Xây dựng đa thức Newton
                y = d(n);
                for k = n-1 : -1 : 1
                    y = y * (X - xa(k)) + d(k);
                end
                y = simplify(y);

            elseif strcmp(app.ChonphuongphapDropDown.Value, 'Lagrange')
                n = length(xa);
                sum = 0;
                for i = 1 : n
                    product = ya(i);
                    for j = 1 : n
                        if j ~= i
                            product = product * ((X - xa(j)) / (xa(i) - xa(j)));
                        end
                    end
                    sum = sum + simplify(product);
                end
                y = simplify(sum);
            end            

            x = linspace(min(xa), max(xa), 100);
            y = double(subs(y, X, x));

            plot(app.UIAxes, xa, ya, 'ro', 'MarkerSize', 8, 'LineWidth', 2); % Dữ liệu thực
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x, y, 'b-', 'LineWidth', 2); % Đa thức nội suy
            hold(app.UIAxes, 'off');

            legend(app.UIAxes, 'Dữ liệu thực', 'Đa thức nội suy');
            title(app.UIAxes, 'Đồ thị hàm nội suy và dữ liệu thực');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhapgiatrixEditFieldLabel = uilabel(app.UIFigure);
            app.NhapgiatrixEditFieldLabel.Text = 'Nhap gia tri x';
            app.NhapgiatrixEditField = uieditfield(app.UIFigure, 'text');
            app.NhapgiatriyEditFieldLabel = uilabel(app.UIFigure);
            app.NhapgiatriyEditFieldLabel.Text = 'Nhap gia tri y';
            app.NhapgiatriyEditField = uieditfield(app.UIFigure, 'text');
            app.ChonphuongphapDropDownLabel = uilabel(app.UIFigure);
            app.ChonphuongphapDropDownLabel.Text = 'Chon phuong phap';
            app.ChonphuongphapDropDown = uidropdown(app.UIFigure);
            app.ChonphuongphapDropDown.Items = {'Newton', 'Lagrange'};
            app.ChonphuongphapDropDown.Value = 'Newton';
            app.KetquadathucButton = uibutton(app.UIFigure, 'push');
            app.KetquadathucButton.ButtonPushedFcn = createCallbackFcn(app, @KetquadathucButtonPushed, true);
            app.KetquadathucButton.Text = 'Ket qua da thuc';
            app.NhapgaitricannoisuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhapgaitricannoisuyEditFieldLabel.Text = 'Nhap gai tri can noi suy';
            app.NhapgaitricannoisuyEditField = uieditfield(app.UIFigure, 'text');
            app.KetquanoisuyButton = uibutton(app.UIFigure, 'push');
            app.KetquanoisuyButton.ButtonPushedFcn = createCallbackFcn(app, @KetquanoisuyButtonPushed, true);
            app.KetquanoisuyButton.Text = 'Ket qua noi suy';
            app.VehamsoButton = uibutton(app.UIFigure, 'push');
            app.VehamsoButton.ButtonPushedFcn = createCallbackFcn(app, @VehamsoButtonPushed, true);
            app.VehamsoButton.Text = 'Ve ham so';
            app.HienthiTextAreaLabel = uilabel(app.UIFigure);
            app.HienthiTextAreaLabel.Text = 'Hien thi';
            app.HienthiTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Noi_Suy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end