classdef HoiQuy_TichPhan1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TchphnTab                     matlab.ui.container.Tab
        tpketqua                      matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel_7         matlab.ui.control.Label
        GiiButton_5                   matlab.ui.control.Button
        fab_5                         matlab.ui.control.Label
        tpN                           matlab.ui.control.NumericEditField
        fab_4                         matlab.ui.control.Label
        fab_3                         matlab.ui.control.Label
        duoi                          matlab.ui.control.NumericEditField
        tren                          matlab.ui.control.NumericEditField
        fab_2                         matlab.ui.control.Label
        NhpdliuyLabel_2               matlab.ui.control.Label
        NhphmLabel_2                  matlab.ui.control.Label
        NhpdliuxLabel_2               matlab.ui.control.Label
        tpfx                          matlab.ui.control.EditField
        tpy                           matlab.ui.control.EditField
        tpx                           matlab.ui.control.EditField
        ChndnguvoDropDown_2           matlab.ui.control.DropDown
        ChndnguvoDropDown_2Label      matlab.ui.control.Label
        ChnphngphpohmDropDown_2       matlab.ui.control.DropDown
        ChnphngphpohmDropDown_2Label  matlab.ui.control.Label
        gx_6                          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ChnphngphpgiiDropDownValueChanged(app, event)

        end

        % Callback function
        function GiiButtonPushed(app, event)
       
        end

        % Callback function
        function GiiButton_2Pushed(app, event)
           
        end

        % Callback function: not associated with a component
        function GiiButton_3Pushed(app, event)

        end

        % Callback function
        function ChndnguvoDropDownValueChanged(app, event)
            value = app.ChndnguvoDropDown.Value;
            switch value 
                case 'Dữ liệu x,y'
                    app.dhx.Visible ='on';
                    app.dhy.Visible ='on';
                    app.dhfx.Visible='off';
                    app.NhpdliuxLabel.Visible='on';
                    app.NhphmLabel.Visible ='off';
                    app.NhpdliuyLabel.Visible='on';
                case 'Hàm số'
                    app.dhx.Visible ='off';
                    app.dhy.Visible ='off';
                    app.dhfx.Visible='on';
                    app.NhpdliuxLabel.Visible='off';
                    app.NhphmLabel.Visible ='on';
                    app.NhpdliuyLabel.Visible='off';
            end
        end

        % Callback function
        function GiiButton_4Pushed(app, event)
         

        end

        % Button pushed function: GiiButton_5
        function GiiButton_5Pushed(app, event)
            seletppp =0;
            seletpinput =0;
            switch app.ChnphngphpohmDropDown_2.Value
                case 'Hình thang'
                    seletppp =1;
                case 'Simpson 1/3'
                    seletppp=2;
                case 'Simpson 3/8'
                    seletppp=3;
            end
            switch app.ChndnguvoDropDown_2.Value
                case 'Dữ liệu x,y'
                    seletpinput =1;
                case 'Hàm số'
                    seletpinput=2;
            end
            x_tpstr = app.tpx.Value;
            y_tpstr = app.tpy.Value;
            x = str2num(x_tpstr);
            y = str2num(y_tpstr);
            tpa = app.duoi.Value;
            tpb = app.tren.Value;
            N = app.tpN.Value;
            %fx = str2func(['@(x)' app.tpfx.Value]);
            if seletppp ==1
                if seletpinput ==1
                    restp= tichphan_hinhthang(x, y, tpa, tpb, N);
                elseif seletpinput ==2
                    fx = str2func(['@(x)' app.tpfx.Value]);
                    restp= hamtichphan_hinhthangfx(fx, tpa, tpb, N);
                end
            elseif seletppp ==2
                if seletpinput ==1
                    restp=  tichphan_simpson13(x, y, tpa, tpb, N);
                elseif seletpinput ==2
                    fx = str2func(['@(x)' app.tpfx.Value]);
                    restp= tichphan_simpson13fx(fx, tpa, tpb, N);
                end  
            elseif seletppp==3
                if seletpinput ==1
                    restp=  tichphan_simpson38(x, y, tpa, tpb, N);
                elseif seletpinput ==2
                    fx = str2func(['@(x)' app.tpfx.Value]);
                    restp= tichphan_simpson38fx(fx, tpa, tpb, N);
                end 
            end
            app.tpketqua.Value =restp;


        end

        % Value changed function: ChnphngphpohmDropDown_2
        function ChnphngphpohmDropDown_2ValueChanged(app, event)
            value = app.ChnphngphpohmDropDown_2.Value;
            
        end

        % Value changed function: ChndnguvoDropDown_2
        function ChndnguvoDropDown_2ValueChanged(app, event)
            value = app.ChndnguvoDropDown_2.Value;
            switch value
                case 'Dữ liệu x,y'
                    app.tpx.Visible ='on';
                    app.tpy.Visible ='on';
                    app.tpfx.Visible='off';
                    app.NhpdliuxLabel_2.Visible='on';
                    app.NhphmLabel_2.Visible ='off';
                    app.NhpdliuyLabel_2.Visible='on';
                    %app.tpfx.Enable = 'off';
                case 'Hàm số'
                    app.tpx.Visible ='off';
                    app.tpy.Visible ='off';
                    app.tpfx.Visible='on';
                    app.NhpdliuxLabel_2.Visible='off';
                    app.NhphmLabel_2.Visible ='on';
                    app.NhpdliuyLabel_2.Visible='off';
                    %app.tpfx.Enable = 'on';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.gx_6 = uilabel(app.TchphnTab);
            app.gx_6.Text = 'TÍCH PHÂN';
            app.ChnphngphpohmDropDown_2Label = uilabel(app.TchphnTab);
            app.ChnphngphpohmDropDown_2Label.Text = 'Chọn phương pháp đạo hàm';
            app.ChnphngphpohmDropDown_2 = uidropdown(app.TchphnTab);
            app.ChnphngphpohmDropDown_2.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.ChnphngphpohmDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChnphngphpohmDropDown_2ValueChanged, true);
            app.ChnphngphpohmDropDown_2.Value = 'Hình thang';
            app.ChndnguvoDropDown_2Label = uilabel(app.TchphnTab);
            app.ChndnguvoDropDown_2Label.Text = 'Chọn dạng đầu vào';
            app.ChndnguvoDropDown_2 = uidropdown(app.TchphnTab);
            app.ChndnguvoDropDown_2.Items = {'...', 'Dữ liệu x,y', 'Hàm số'};
            app.ChndnguvoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChndnguvoDropDown_2ValueChanged, true);
            app.ChndnguvoDropDown_2.Value = '...';
            app.tpx = uieditfield(app.TchphnTab, 'text');
            app.tpy = uieditfield(app.TchphnTab, 'text');
            app.tpfx = uieditfield(app.TchphnTab, 'text');
            app.NhpdliuxLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuxLabel_2.Text = 'Nhập dữ liệu x: ';
            app.NhphmLabel_2 = uilabel(app.TchphnTab);
            app.NhphmLabel_2.Text = 'Nhập hàm: ';
            app.NhpdliuyLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuyLabel_2.Text = 'Nhập dữ liệu y: ';
            app.fab_2 = uilabel(app.TchphnTab);
            app.fab_2.Text = 'Nhập cận tính tích phân:  ';
            app.tren = uieditfield(app.TchphnTab, 'numeric');
            app.duoi = uieditfield(app.TchphnTab, 'numeric');
            app.fab_3 = uilabel(app.TchphnTab);
            app.fab_3.Text = 'Cận trên:';
            app.fab_4 = uilabel(app.TchphnTab);
            app.fab_4.Text = 'Cận dưới:';
            app.tpN = uieditfield(app.TchphnTab, 'numeric');
            app.fab_5 = uilabel(app.TchphnTab);
            app.fab_5.Text = 'Nhập N';
            app.GiiButton_5 = uibutton(app.TchphnTab, 'push');
            app.GiiButton_5.ButtonPushedFcn = createCallbackFcn(app, @GiiButton_5Pushed, true);
            app.GiiButton_5.Text = 'Giải';
            app.NhpgitrcnnisuyLabel_7 = uilabel(app.TchphnTab);
            app.NhpgitrcnnisuyLabel_7.Text = 'Kết quả tích phân: ';
            app.tpketqua = uieditfield(app.TchphnTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoiQuy_TichPhan1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end