classdef tiemnghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        NghimTab                     matlab.ui.container.Tab
        gx_2                         matlab.ui.control.Label
        ptgx                         matlab.ui.control.EditField
        gx                           matlab.ui.control.Label
        fab                          matlab.ui.control.Label
        GiiButton                    matlab.ui.control.Button
        lap                          matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        kq                           matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel      matlab.ui.control.Label
        ChnphngphpgiiDropDown        matlab.ui.control.DropDown
        ChnphngphpgiiDropDownLabel   matlab.ui.control.Label
        ss                           matlab.ui.control.NumericEditField
        b                            matlab.ui.control.NumericEditField
        a                            matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel  matlab.ui.control.Label
        pt                           matlab.ui.control.EditField
        NhpphngtrnhLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChnphngphpgiiDropDown
        function ChnphngphpgiiDropDownValueChanged(app, event)

            switch app.ChnphngphpgiiDropDown.Value
                case 'Chia đôi'
                    app.b.Visible = 'on';
                    app.gx.Visible ='off';
                    app.ptgx.Visible = 'off';
                    app.fab.Text = 'Nhập khoảng nghiệm phân ly [a b]: ';
                case 'Lặp'
                    app.fab.Text = 'Nhập giá trị khởi tạo: ';
                    app.b.Visible = 'off';
                    app.gx.Text = 'Nhập hàm lặp g(x): ';
                    app.gx.Visible ='on';
                    app.ptgx.Visible ='on';
                case 'Newton'
                    app.fab.Text = 'Nhập giá trị khởi tạo: ';
                    app.b.Visible = 'off';
                    app.gx.Text = 'Đạo hàm của f(x): ';
                    app.gx.Visible ='on';
                    app.ptgx.Visible ='on';
                    
            end
            
        end

        % Button pushed function: GiiButton
        function GiiButtonPushed(app, event)
            select =0;
            switch app.ChnphngphpgiiDropDown.Value
                case 'Chia đôi'
                    select =1;
                case 'Lặp'
                    select =2;
                case 'Newton'
                    select =3;
            end
            fx = str2func(['@(x)' app.pt.Value]);
            saiso = app.ss.Value;
            if select ==1
                a = app.a.Value;
                b= app.b.Value;            
                [result, n] = chia_doi(fx, a, b, saiso);
                
            elseif select ==2
                x0 = app.a.Value;
                g =str2func(['@(x)' app.ptgx.Value]);
                [result, n] = lap(fx, g, x0, saiso);
            elseif select ==3
                x0 = app.a.Value;
                daoham = str2func(['@(x)' app.ptgx.Value]);
                [result, n] = newton(fx, daoham, x0, saiso);
            end
            app.kq.Value = result;
            app.lap.Value= n;
            fplot(app.UIAxes,fx, 'DisplayName', 'f(x)');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,result, fx(result), 'ro', 'DisplayName', 'Nghiệm gần đúng');
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'f(x)');
            title(app.UIAxes,'Đồ thị của phương trình f(x)');
            legend(app.UIAxes,'show');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
            %close(1);
                    

        end

        % Callback function: not associated with a component
        function GiiButton_2Pushed(app, event)

            
        end

        % Callback function: not associated with a component
        function GiiButton_3Pushed(app, event)
     

        end

        % Callback function: not associated with a component
        function ChndnguvoDropDownValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function GiiButton_4Pushed(app, event)


        end

        % Callback function: not associated with a component
        function GiiButton_5Pushed(app, event)

        end

        % Callback function: not associated with a component
        function ChndnguvoDropDown_2ValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình f(x): ';
            app.pt = uieditfield(app.NghimTab, 'text');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép: ';
            app.a = uieditfield(app.NghimTab, 'numeric');
            app.b = uieditfield(app.NghimTab, 'numeric');
            app.ss = uieditfield(app.NghimTab, 'numeric');
            app.ChnphngphpgiiDropDownLabel = uilabel(app.NghimTab);
            app.ChnphngphpgiiDropDownLabel.Text = 'Chọn phương pháp giải';
            app.ChnphngphpgiiDropDown = uidropdown(app.NghimTab);
            app.ChnphngphpgiiDropDown.Items = {'...', 'Chia đôi', 'Lặp', 'Newton'};
            app.ChnphngphpgiiDropDown.ValueChangedFcn = createCallbackFcn(app, @ChnphngphpgiiDropDownValueChanged, true);
            app.ChnphngphpgiiDropDown.Value = '...';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm: ';
            app.kq = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.lap = uieditfield(app.NghimTab, 'numeric');
            app.GiiButton = uibutton(app.NghimTab, 'push');
            app.GiiButton.ButtonPushedFcn = createCallbackFcn(app, @GiiButtonPushed, true);
            app.GiiButton.Text = 'Giải';
            app.fab = uilabel(app.NghimTab);
            app.fab.Text = 'Nhập khoảng nghiệm phân ly [a b]: ';
            app.gx = uilabel(app.NghimTab);
            app.gx.Text = 'Nhập hàm lặp g(x): ';
            app.ptgx = uieditfield(app.NghimTab, 'text');
            app.gx_2 = uilabel(app.NghimTab);
            app.gx_2.Text = 'GIẢI PHƯƠNG TRÌNH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tiemnghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end